classdef GSDMx_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        FileSelectionButton             matlab.ui.container.ButtonGroup
        OnlySelectionButton             matlab.ui.control.ToggleButton
        AllFilesButton                  matlab.ui.control.ToggleButton
        OutputDirectoryLocationLabel    matlab.ui.control.Label
        OutputBrowseButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        DirectoryLocationLabel          matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        OutputAnalysisFolderLabel       matlab.ui.control.Label
        InputFilesLabel                 matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        V13Label                        matlab.ui.control.Label
        NextImageButton                 matlab.ui.control.Button
        PreviousImageButton             matlab.ui.control.Button
        MatthewLeipnerLabel             matlab.ui.control.Label
        BiophysicsLabLabel              matlab.ui.control.Label
        ETHZrichDBSSELabel              matlab.ui.control.Label
        GSDMxAnalysisToolLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        Classifier                      matlab.ui.control.DropDown
        ClassifierLabel                 matlab.ui.control.Label
        RunStatisticalAnalysisButton    matlab.ui.control.Button
        StopButton                      matlab.ui.control.StateButton
        PauseButton                     matlab.ui.control.StateButton
        AnalyzeButton                   matlab.ui.control.Button
        MinCircularity                  matlab.ui.control.NumericEditField
        MinimumCircularityLabel         matlab.ui.control.Label
        MaxMajorAxis                    matlab.ui.control.NumericEditField
        MaxMajorAxisnmEditFieldLabel    matlab.ui.control.Label
        LargeSizeFilter                 matlab.ui.control.NumericEditField
        LargeSizeFilternm2Label         matlab.ui.control.Label
        SmallSizeFilter                 matlab.ui.control.NumericEditField
        SmallSizeFilternm2Label         matlab.ui.control.Label
        CommonlyChangedParametersLabel  matlab.ui.control.Label
        InfrequentlyChangedParametersLabel  matlab.ui.control.Label
        ProteinMaxHeight                matlab.ui.control.NumericEditField
        ProteinMaxHeightnmEditFieldLabel  matlab.ui.control.Label
        OligoMaxHeight                  matlab.ui.control.NumericEditField
        OligoMaxHeightnmEditFieldLabel  matlab.ui.control.Label
        UseOldFlattening                matlab.ui.control.CheckBox
        AnalysisType                    matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = public)
        % Initialize image counters
        cImage;
        numImages;

        % Initialize file tracker
        inputDir;
        outDir = './analysis';
        files;
        
        % Initialize Selected Files
        selectedFiles;

    end

    methods (Access = private)

        function updatePlot(app)
            addpath(genpath('resources/MATLAB_helper_scripts'));
            [AFM_images, channelInfo] = openNano6([app.files(app.cImage).folder,'/',app.files(app.cImage).name]);

            M = AFM_images(:,:,1);

            [samplePoints, ~] = size(M);             % number of samples/line
            imSize = channelInfo(1).Width;      % scan size [nm]

            x = zeros(samplePoints) + linspace(0,imSize,samplePoints);
            y = rot90(zeros(samplePoints) + linspace(0,imSize,samplePoints));
            z = M;

            surf(app.UIAxes,x,y,z-min(min(z)),'LineStyle','none');
            xlabel(app.UIAxes,'X [nm]','FontSize',10);
            ylabel(app.UIAxes,'Y [nm]','FontSize',10);
            zlabel(app.UIAxes,'Height [nm]','FontSize',10);
            title(app.UIAxes,app.files(app.cImage).name);
            app.UIAxes.Visible = 'on';
        end
    end

    methods (Access = public)

        function checkPauseStopStatus(app,flag)
            addpath(genpath('./resources/MATLAB_helper_scripts'));
            % This function can be called at any time to check that status of the pause and stop buttons.
            % If paused, it will wait until un-paused.
            % If stopped, it will throw an error to break execution. The error will not be thrown.
            % If flag input is included and =1, buttons will be reset to de-selected.
            % if flag input is included and =2, all buttons will be disabled (used during file writing and other processes)
            % if flag input is included and =3, all buttons will be enabled (used during file writing and other processes)
            if nargin > 1
                switch flag
                    case 1 % reset pause/stop button
                        app.PauseButton.Value = false;
                        PauseButtonValueChanged(app, [])
                        app.StopButton.Value = false;
                    case 2 % disable buttons
                        app.PauseButton.Enable = 'off';
                        app.StopButton.Enable = 'off';
                    case 3 % enable buttons
                        app.PauseButton.Enable = 'on';
                        app.StopButton.Enable = 'on';
                    otherwise
                        error('Unknown flag value.')
                end
                drawnow()
            else
                if app.StopButton.Value
                    checkPauseStopStatus(app,1) % reset pause/stop buttons
                    
                    app.AnalyzeButton.Icon = '';
                    app.AnalyzeButton.Text = 'User Aborted';
                    
                    error('MATLAB:eyeTrackAPP:abort','User aborted execution.')
                end
                if app.PauseButton.Value
                    waitfor(app.PauseButton,'Value',false)
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            % Add path to helper Matlab code needed later on
            addpath(genpath('./resources/MATLAB_helper_scripts'));

            app.inputDir = uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            drawnow;
            figure(app.UIFigure)
            app.DirectoryLocationLabel.Text = path;  % Sets the label text to be the selected path

            app.files = dir(fullfile(app.inputDir,'**/*.0*'));
            b={app.files(:).name}';                      % Gets the name of the app.files/folders of the contents and stores them appropriately in a cell array

            app.UITable.Data=b;                  % Displays the directory information to the UITable.

            % Set preview image to first image in the list
            app.numImages = length(b);
            app.cImage = 1;
            updatePlot(app)

            % Highlight Table Row
            S = uistyle('BackgroundColor', 'yellow');
            addStyle(app.UITable, S, 'cell', [app.cImage 1]);
            
            % Default selction for image processing to all
            app.selectedFiles = 1:length(app.files);
        end

        % Button pushed function: OutputBrowseButton
        function OutputBrowseButtonPushed(app, event)
            app.outDir = uigetdir;                 % Calls 'uigetdir' to obtain the directory location from the user
            drawnow;
            figure(app.UIFigure)
            app.OutputDirectoryLocationLabel.Text = app.outDir;  % Sets the label text to be the selected path
        end

        % Button pushed function: PreviousImageButton
        function PreviousImageButtonPushed(app, event)
            app.cImage = app.cImage - 1;

            % Check if we crossed to 0, in which case we restart at top of
            % list
            if app.cImage < 1
                app.cImage = app.numImages;
            end

            % Update plot
            updatePlot(app)

            % Remove Highlights from Table
            removeStyle(app.UITable);
            % Highlight Table Row
            S = uistyle('BackgroundColor', 'yellow');
            addStyle(app.UITable, S, 'cell', [app.cImage 1]);
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            app.cImage = app.cImage + 1;

            % Check if we crossed to 0, in which case we restart at top of
            % list
            if app.cImage > app.numImages
                app.cImage = 1;
            end

            % Update plot
            updatePlot(app)

            % Remove Highlights from Table
            removeStyle(app.UITable);
            % Highlight Table Row
            S = uistyle('BackgroundColor', 'yellow');
            addStyle(app.UITable, S, 'cell', [app.cImage 1]);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;

            app.cImage = indices(1);

            % Update plot
            updatePlot(app)

            % Remove Highlights from Table
            removeStyle(app.UITable);
            % Highlight Table Row
            S = uistyle('BackgroundColor', 'yellow');
            addStyle(app.UITable, S, 'cell', [app.cImage 1]);
            
            % Switch between processing all files and processing only a
            % selection of files
            if app.AllFilesButton.Value
                app.selectedFiles = 1:length(app.files);
            else
                app.selectedFiles = indices(:,1);
            end
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % Load functions
            addpath(genpath('./resources/MATLAB_helper_scripts'));
            addpath(genpath('./workflow'));

            % Change button name to "Processing"
            app.AnalyzeButton.Text = 'Processing...';
            % Put text on top of icon
            app.AnalyzeButton.IconAlignment = 'bottom';
            % Create waitbar with same color as button
            wbar = permute(repmat(app.AnalyzeButton.BackgroundColor,15,1,200),[1,3,2]);
            % Black frame around waitbar
            wbar([1,end],:,:) = 0;
            wbar(:,[1,end],:) = 0;
            % Load the empty waitbar to the button
            app.AnalyzeButton.Icon = wbar;

            % Start code and update waitbar
            GSDMx_App_Analysis(app)

            % remove waitbar
            app.AnalyzeButton.Icon = '';
            % Change button name

            app.AnalyzeButton.Text = 'Analyze';
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            if app.PauseButton.Value
                app.StopButton.Enable = 'off';
                app.PauseButton.Text = 'Resume';
                app.AnalyzeButton.Text = 'Paused';
            else
                app.StopButton.Enable = 'on';
                app.PauseButton.Text = 'Pause';
                app.AnalyzeButton.Text = 'Processing...';
            end

        end

        % Button pushed function: RunStatisticalAnalysisButton
        function RunStatisticalAnalysisButtonPushed(app, event)
            % Change button name to "Processing"
            app.RunStatisticalAnalysisButton.Text = 'Processing...';
            
            status = system('chmod u+x workflow/GSDMx_SummaryStatistics.R');
            status = system('workflow/GSDMx_SummaryStatistics.R');
            
            if status == 0
                app.RunStatisticalAnalysisButton.Text = 'Success';
            else
                app.RunStatisticalAnalysisButton.Text = 'Error';     
            end
            
            pause(5)
            
            app.RunStatisticalAnalysisButton.Text = 'Run Statistical Analysis';
            
        end

        % Value changed function: AnalysisType
        function AnalysisTypeValueChanged(app, event)
            value = app.AnalysisType.Value;
            
            if value == "Automated Full Analysis"
                app.Classifier.Enable = 'on';
            else
                app.Classifier.Enable = 'off';
            end
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {596, 596, 596};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {596, 596};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {426, '1x', 214};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InputFilesLabel = uilabel(app.LeftPanel);
            app.InputFilesLabel.Text = 'Input Files';
            app.OutputAnalysisFolderLabel = uilabel(app.LeftPanel);
            app.OutputAnalysisFolderLabel.Text = 'Output/Analysis Folder';
            app.BrowseButton = uibutton(app.LeftPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DirectoryLocationLabel = uilabel(app.LeftPanel);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'Directory Contents'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.OutputBrowseButton = uibutton(app.LeftPanel, 'push');
            app.OutputBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @OutputBrowseButtonPushed, true);
            app.OutputBrowseButton.Text = 'Browse';
            app.OutputDirectoryLocationLabel = uilabel(app.LeftPanel);
            app.OutputDirectoryLocationLabel.Text = '''./../analysis''';
            app.FileSelectionButton = uibuttongroup(app.LeftPanel);
            app.AllFilesButton = uitogglebutton(app.FileSelectionButton);
            app.AllFilesButton.Tooltip = {'"All" Will process all images in the above list, independent of highlighted values.'};
            app.AllFilesButton.Text = 'All';
            app.AllFilesButton.Value = true;
            app.OnlySelectionButton = uitogglebutton(app.FileSelectionButton);
            app.OnlySelectionButton.Tooltip = {'"Only Selection" Will set up the script to only process the highlighted/selected images in the above list.'};
            app.OnlySelectionButton.Text = 'Only Selection';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GSDMxAnalysisToolLabel = uilabel(app.CenterPanel);
            app.GSDMxAnalysisToolLabel.Text = 'GSDMx Analysis Tool';
            app.ETHZrichDBSSELabel = uilabel(app.CenterPanel);
            app.ETHZrichDBSSELabel.Text = 'ETH Zürich, D-BSSE';
            app.BiophysicsLabLabel = uilabel(app.CenterPanel);
            app.BiophysicsLabLabel.Text = 'Biophysics Lab';
            app.MatthewLeipnerLabel = uilabel(app.CenterPanel);
            app.MatthewLeipnerLabel.Text = 'Matthew Leipner';
            app.PreviousImageButton = uibutton(app.CenterPanel, 'push');
            app.PreviousImageButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousImageButtonPushed, true);
            app.PreviousImageButton.Text = '<-- Previous Image';
            app.NextImageButton = uibutton(app.CenterPanel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image -->';
            app.V13Label = uilabel(app.CenterPanel);
            app.V13Label.Text = 'V1.3';
            app.RightPanel = uipanel(app.GridLayout);
            app.AnalysisTypeDropDownLabel = uilabel(app.RightPanel);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisType = uidropdown(app.RightPanel);
            app.AnalysisType.Items = {'Surface Coverage', 'Manual Full Analysis', 'Automated Full Analysis'};
            app.AnalysisType.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeValueChanged, true);
            app.AnalysisType.Value = 'Surface Coverage';
            app.UseOldFlattening = uicheckbox(app.RightPanel);
            app.UseOldFlattening.Text = 'Use Old Flattening';
            app.OligoMaxHeightnmEditFieldLabel = uilabel(app.RightPanel);
            app.OligoMaxHeightnmEditFieldLabel.Text = 'Oligo Max Height [nm]';
            app.OligoMaxHeight = uieditfield(app.RightPanel, 'numeric');
            app.OligoMaxHeight.Value = 4;
            app.ProteinMaxHeightnmEditFieldLabel = uilabel(app.RightPanel);
            app.ProteinMaxHeightnmEditFieldLabel.Text = 'Protein Max Height [nm]';
            app.ProteinMaxHeight = uieditfield(app.RightPanel, 'numeric');
            app.ProteinMaxHeight.Value = 9;
            app.InfrequentlyChangedParametersLabel = uilabel(app.RightPanel);
            app.InfrequentlyChangedParametersLabel.Text = '    Infrequently   Changed Parameters';
            app.CommonlyChangedParametersLabel = uilabel(app.RightPanel);
            app.CommonlyChangedParametersLabel.Text = '     Commonly      Changed Parameters';
            app.SmallSizeFilternm2Label = uilabel(app.RightPanel);
            app.SmallSizeFilternm2Label.Text = 'Small Size Filter [nm2]';
            app.SmallSizeFilter = uieditfield(app.RightPanel, 'numeric');
            app.SmallSizeFilter.Value = 350;
            app.LargeSizeFilternm2Label = uilabel(app.RightPanel);
            app.LargeSizeFilternm2Label.Text = 'Large Size Filter [nm2]';
            app.LargeSizeFilter = uieditfield(app.RightPanel, 'numeric');
            app.LargeSizeFilter.Value = 10000;
            app.MaxMajorAxisnmEditFieldLabel = uilabel(app.RightPanel);
            app.MaxMajorAxisnmEditFieldLabel.Text = 'Max Major Axis [nm]';
            app.MaxMajorAxis = uieditfield(app.RightPanel, 'numeric');
            app.MaxMajorAxis.Value = 137;
            app.MinimumCircularityLabel = uilabel(app.RightPanel);
            app.MinimumCircularityLabel.Text = 'Minimum Circularity [-]';
            app.MinCircularity = uieditfield(app.RightPanel, 'numeric');
            app.MinCircularity.Value = 0.3;
            app.AnalyzeButton = uibutton(app.RightPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.PauseButton = uibutton(app.RightPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.RightPanel, 'state');
            app.StopButton.Text = 'Stop';
            app.RunStatisticalAnalysisButton = uibutton(app.RightPanel, 'push');
            app.RunStatisticalAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunStatisticalAnalysisButtonPushed, true);
            app.RunStatisticalAnalysisButton.Tooltip = {'Only run after processing main analysis with the "Analyze" button.'};
            app.RunStatisticalAnalysisButton.Text = 'Run Statistical Analysis';
            app.ClassifierLabel = uilabel(app.RightPanel);
            app.ClassifierLabel.Enable = 'off';
            app.ClassifierLabel.Text = 'Classifier';
            app.Classifier = uidropdown(app.RightPanel);
            app.Classifier.Items = {'Naive Bayes', 'KNN', 'SVM'};
            app.Classifier.Enable = 'off';
            app.Classifier.Value = 'Naive Bayes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GSDMx_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end